classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ZPanel          matlab.ui.container.Panel
        rTextArea       matlab.ui.control.TextArea
        rSlider         matlab.ui.control.Slider
        rSlider_6Label  matlab.ui.control.Label
        HTextArea       matlab.ui.control.TextArea
        HSlider         matlab.ui.control.Slider
        HSliderLabel    matlab.ui.control.Label
        h1TextArea      matlab.ui.control.TextArea
        h1Slider        matlab.ui.control.Slider
        h1SliderLabel   matlab.ui.control.Label
        h2TextArea      matlab.ui.control.TextArea
        h2Slider        matlab.ui.control.Slider
        h2SliderLabel   matlab.ui.control.Label
        hTextArea       matlab.ui.control.TextArea
        hSlider         matlab.ui.control.Slider
        hSliderLabel    matlab.ui.control.Label
        h_dTextArea     matlab.ui.control.TextArea
        XPanel          matlab.ui.container.Panel
        h_dSlider       matlab.ui.control.Slider
        h_dSliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        surf_axes_x, surf_axes_z;
        params;
    end
    
    methods (Access = private)
        
        function setParamTextArea (app, name_param, value)
            switch name_param
                case 'r'
                    app.rTextArea.Value = value;
                case 'h1'
                    app.h1TextArea.Value = value;
                case 'h2'
                    app.h2TextArea.Value = value;
                case 'H'
                    app.HTextArea.Value = value;
                case 'h'
                    app.hTextArea.Value = value;
                case 'h_d'
                    app.h_dTextArea.Value = value;
                otherwise
                    error 'Incorrect name param'
            end
        end
        
        function setParamSliderValue (app, name_param, value)
            switch name_param
                case 'r'
                    app.rSlider.Value = value;
                case 'h1'
                    app.h1Slider.Value = value;
                case 'h2'
                    app.h2Slider.Value = value;
                case 'H'
                    app.HSlider.Value = value;
                case 'h'
                    app.hSlider.Value = value;
                case 'h_d'
                    app.h_dSlider.Value = value;
                otherwise
                    error 'Incorrect name param'
            end
        end
        
        function init_text_areas (app)
            app.setParamTextArea ('r',   num2str (app.params.r  ));
            app.setParamTextArea ('h1',  num2str (app.params.h1 ));
            app.setParamTextArea ('h2',  num2str (app.params.h2 ));
            app.setParamTextArea ('H',   num2str (app.params.H  ));
            app.setParamTextArea ('h',   num2str (app.params.h  ));
            app.setParamTextArea ('h_d', num2str (app.params.h_d));
            
            app.setParamSliderValue ('r',   app.params.r  );
            app.setParamSliderValue ('h1',  app.params.h1 );
            app.setParamSliderValue ('h2',  app.params.h2 );
            app.setParamSliderValue ('H',   app.params.H  );
            app.setParamSliderValue ('h',   app.params.h  );
            app.setParamSliderValue ('h_d', app.params.h_d);
        end
        
        function update (app, name_param, value)
            app.params.set (name_param, value);
            app.setParamTextArea (name_param, num2str (value));
        end
        
        function drawSurf (app)
            try
                app.XPanel.Visible = 'On';
                draw_mv1_surf (app.params, app.surf_axes_x, app.surf_axes_z);
            catch
                app.XPanel.Visible = 'Off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, t, y)
            app.surf_axes_x = axes (app.XPanel);
            app.surf_axes_z = axes (app.ZPanel);
            
            app.params = mv1_params_t ();
            
            % Load mv1 params
            mv1_params;
            app.params = params; %#ok<ADPROPLC> 
            clear params;
            
            app.init_text_areas ();
            
            app.drawSurf ();
        end

        % Value changing function: h_dSlider
        function h_dSliderValueChanging(app, event)
            app.update ('h_d', event.Value);
            app.drawSurf ();
        end

        % Value changed function: h_dSlider
        function h_dSliderValueChanged(app, event)
            app.update ('h_d', event.Value);
            app.drawSurf ();
        end

        % Value changing function: rSlider
        function rSliderValueChanging(app, event)
            app.update ('r', event.Value);
            app.drawSurf ();
        end

        % Value changed function: rSlider
        function rSliderValueChanged(app, event)
            app.update ('r', event.Value);
            app.drawSurf ();
        end

        % Value changed function: HSlider
        function HSliderValueChanged(app, event)
            app.update ('H', event.Value);
            app.drawSurf ();
        end

        % Value changing function: HSlider
        function HSliderValueChanging(app, event)
            app.update ('H', event.Value);
            app.drawSurf ();
        end

        % Value changed function: h1Slider
        function h1SliderValueChanged(app, event)
            app.update ('h1', event.Value);
            app.drawSurf ();
        end

        % Value changing function: h1Slider
        function h1SliderValueChanging(app, event)
            app.update ('h1', event.Value);
            app.drawSurf ();
        end

        % Value changing function: h2Slider
        function h2SliderValueChanging(app, event)
            app.update ('h2', event.Value);
            app.drawSurf ();
        end

        % Value changed function: h2Slider
        function h2SliderValueChanged(app, event)
            app.update ('h2', event.Value);
            app.drawSurf ();
        end

        % Value changed function: hSlider
        function hSliderValueChanged(app, event)
            app.update ('h', event.Value);
            app.drawSurf ();
        end

        % Value changing function: hSlider
        function hSliderValueChanging(app, event)
            app.update ('h', event.Value);
            app.drawSurf ();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.h_dSliderLabel = uilabel(app.UIFigure);
            app.h_dSliderLabel.Text = 'h_d';
            app.h_dSlider = uislider(app.UIFigure);
            app.h_dSlider.Limits = [0 20];
            app.h_dSlider.ValueChangedFcn = createCallbackFcn(app, @h_dSliderValueChanged, true);
            app.h_dSlider.ValueChangingFcn = createCallbackFcn(app, @h_dSliderValueChanging, true);
            app.XPanel = uipanel(app.UIFigure);
            app.XPanel.Title = 'X';
            app.h_dTextArea = uitextarea(app.UIFigure);
            app.hSliderLabel = uilabel(app.UIFigure);
            app.hSliderLabel.Text = 'h';
            app.hSlider = uislider(app.UIFigure);
            app.hSlider.Limits = [0 5];
            app.hSlider.ValueChangedFcn = createCallbackFcn(app, @hSliderValueChanged, true);
            app.hSlider.ValueChangingFcn = createCallbackFcn(app, @hSliderValueChanging, true);
            app.hTextArea = uitextarea(app.UIFigure);
            app.h2SliderLabel = uilabel(app.UIFigure);
            app.h2SliderLabel.Text = 'h2';
            app.h2Slider = uislider(app.UIFigure);
            app.h2Slider.Limits = [0 30];
            app.h2Slider.ValueChangedFcn = createCallbackFcn(app, @h2SliderValueChanged, true);
            app.h2Slider.ValueChangingFcn = createCallbackFcn(app, @h2SliderValueChanging, true);
            app.h2TextArea = uitextarea(app.UIFigure);
            app.h1SliderLabel = uilabel(app.UIFigure);
            app.h1SliderLabel.Text = 'h1';
            app.h1Slider = uislider(app.UIFigure);
            app.h1Slider.Limits = [0 30];
            app.h1Slider.ValueChangedFcn = createCallbackFcn(app, @h1SliderValueChanged, true);
            app.h1Slider.ValueChangingFcn = createCallbackFcn(app, @h1SliderValueChanging, true);
            app.h1TextArea = uitextarea(app.UIFigure);
            app.HSliderLabel = uilabel(app.UIFigure);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.UIFigure);
            app.HSlider.Limits = [0 50];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider.ValueChangingFcn = createCallbackFcn(app, @HSliderValueChanging, true);
            app.HTextArea = uitextarea(app.UIFigure);
            app.rSlider_6Label = uilabel(app.UIFigure);
            app.rSlider_6Label.Text = 'r';
            app.rSlider = uislider(app.UIFigure);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @rSliderValueChanged, true);
            app.rSlider.ValueChangingFcn = createCallbackFcn(app, @rSliderValueChanging, true);
            app.rTextArea = uitextarea(app.UIFigure);
            app.ZPanel = uipanel(app.UIFigure);
            app.ZPanel.Title = 'Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end